classdef Dido02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        InicioTab                      matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Label_2                        matlab.ui.control.Label
        SistemadegestindedosisButtonGroup  matlab.ui.container.ButtonGroup
        DOLQAButton                    matlab.ui.control.RadioButton
        DoseWisePortalButton           matlab.ui.control.RadioButton
        Image2                         matlab.ui.control.Image
        DIDo2019VersinenpruebasNoaptaparausoclnicoRobertoSnchezLabel  matlab.ui.control.Label
        SeleccionalosarchivosLabel     matlab.ui.control.Label
        BuscararchivosButton           matlab.ui.control.StateButton
        TextAreaArchivos               matlab.ui.control.TextArea
        ContinuarButton                matlab.ui.control.StateButton
        ErrorleyendoficherosVerificaelformatodelficheroLabel  matlab.ui.control.Label
        DOLQATab                       matlab.ui.container.Tab
        Label_3                        matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        SeleccionacentroDropDownLabel  matlab.ui.control.Label
        SeleccionacentroDropDown       matlab.ui.control.DropDown
        SeleccionaSalaDropDownLabel    matlab.ui.control.Label
        SeleccionaSalaDropDown         matlab.ui.control.DropDown
        SinoseleccionassalaserealizarlaestimacinconunasalaestndarLabel  matlab.ui.control.Label
        PesokgLabel                    matlab.ui.control.Label
        PesokgEditField                matlab.ui.control.NumericEditField
        DatosdelpacienteLabel          matlab.ui.control.Label
        EdadaosEditFieldLabel          matlab.ui.control.Label
        EdadaosEditField               matlab.ui.control.NumericEditField
        AlturacmEditFieldLabel         matlab.ui.control.Label
        AlturacmEditField              matlab.ui.control.NumericEditField
        InformaDatosPaciente           matlab.ui.control.Label
        SexoLabel                      matlab.ui.control.Label
        SeleccionarSexo                matlab.ui.control.DropDown
        Introducirunaedadentre0y130aosLabel  matlab.ui.control.Label
        Introducirpesoentre05y250kgLabel  matlab.ui.control.Label
        Introduciralturaentre20y220cmLabel  matlab.ui.control.Label
        CalcularmapadedosisButton_DOLQA  matlab.ui.control.Button
        DoseWiseTab                    matlab.ui.container.Tab
        InformacionerroresLabel        matlab.ui.control.Label
        Image4                         matlab.ui.control.Image
        CalcularmapadedosisButton      matlab.ui.control.Button
        ResultadosTab                  matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        CrearInformeButton             matlab.ui.control.Button
    end

    properties (Access = public)
        ruta; % ruta de los ficheros
        fichero; % nombre de los ficheros
        estudios; % struct de los estudios
        eventos; % struct de los eventos
        Informe; % objeto informe de dosis
        mapadosis; % v1, v2, v3, color
        calclog; % informe de las incidencias del calculo del mapa de dosis
        mensaje % mensaje tras leer información del fichero RDSR
        tcalc % tiempo de proceso
        centros; % array listado de centros
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BuscararchivosButton
        function BuscararchivosButtonValueChanged(app, event)
            app.ErrorleyendoficherosVerificaelformatodelficheroLabel.Visible = false;
            app.TextAreaArchivos.Visible = true;
            app.TextAreaArchivos.Value = '';
            [fich,rut] = uigetfile('*.xls; *.xlsx','Multiselect','on');
            app.ruta = rut;
            app.fichero = fich;
            app.TextAreaArchivos.Value = fich;
            app.ContinuarButton.Visible = 'on';   
            app.ContinuarButton.Enable = 'on';
        end

        % Value changed function: ContinuarButton
        function ContinuarButtonValueChanged(app, event)
            switch app.SistemadegestindedosisButtonGroup.SelectedObject.Text
                %
                %%%%%%%%%%%%%% DOLQA
                %
                case 'DOLQA'
                    app.DOLQATab.Parent = app.TabGroup;
                    try
                        if class(app.fichero) == 'cell'
                            [app.eventos, app.mensaje] = LeerDOLQA(strcat(app.ruta,app.fichero));
                            app.estudios = strings(length(app.fichero),1);
                            for i=1:length(app.fichero)
                                [~,app.estudios(i),~] = fileparts(app.fichero{i});
                            end                            
                        else
                            [app.eventos, app.mensaje] = LeerDOLQA({strcat(app.ruta,app.fichero)});
                            [~,app.estudios,~] = fileparts(app.fichero);
                            app.estudios = convertCharsToStrings(app.estudios);
                        end                        
                        app.TabGroup.SelectedTab = app.DOLQATab;
                        app.centros = readtable('D:\DIDO\doc\Equipos_rx\centros.xlsx');
                        %app.centros = readtable('centros.xlsx');
                        app.SeleccionacentroDropDown.Items = app.centros.Nombre;
                        app.DoseWiseTab.Parent = [];
                    catch ME                
                        app.ErrorleyendoficherosVerificaelformatodelficheroLabel.Visible = true;
                    end
                %%%% DoseWise    
                case 'DoseWisePortal'
                    app.DoseWiseTab.Parent = app.TabGroup;
                    try
                        if class(app.fichero) == 'cell'
                            [app.estudios, app.eventos] = LeerDoseWise(strcat(app.ruta,app.fichero));
                        else
                            [app.estudios, app.eventos] = LeerDoseWise({strcat(app.ruta,app.fichero)});
                        end
                        [app.mensaje, interrumpir] = checkDoseWise(app.estudios, app.eventos);
                        app.TabGroup.SelectedTab = app.DoseWiseTab;
                        app.DOLQATab.Parent = [];
                        app.InformacionerroresLabel.Visible = true;
                        app.InformacionerroresLabel.Text = app.mensaje;
                        if interrumpir == 0
                            app.CalcularmapadedosisButton.Visible = true;
                        end
                    catch ME                
                        app.ErrorleyendoficherosVerificaelformatodelficheroLabel.Visible = true;
                    end                    
            end
        end

        % Value changed function: SeleccionacentroDropDown
        function SeleccionacentroDropDownValueChanged(app, event)
            salas = readtable('D:\DIDO\doc\Equipos_rx\salas.xlsx');
            %salas = readtable('salas.xlsx');
            centro = app.centros(strcmp(app.centros.Nombre, app.SeleccionacentroDropDown.Value),1).Id;
            salas = salas(startsWith(salas.ID, centro),:);
            app.SeleccionaSalaDropDown.Items = salas.ID;
            app.CalcularmapadedosisButton_DOLQA.Visible = true;
        end

        % Value changed function: SeleccionaSalaDropDown
        function SeleccionaSalaDropDownValueChanged(app, event)
            app.eventos.StationName(:) = {app.SeleccionaSalaDropDown.Value};
            app.mensaje(1,1) = strcat('El usuario ha seleccionado un equipo de rayos-X:',app.eventos.StationName(1));
        end

        % Value changed function: SeleccionarSexo
        function SeleccionarSexoValueChanged(app, event)
            app.eventos.PatientSex(:) = {app.SeleccionarSexo.Value};
        end

        % Value changed function: EdadaosEditField
        function EdadaosEditFieldValueChanged(app, event)
            if (app.EdadaosEditField.Value < 0 || app.EdadaosEditField.Value > 130)
                app.Introducirunaedadentre0y130aosLabel.Visible = true;
            else
                app.eventos.PatientAge(:) = app.EdadaosEditField.Value;
                app.Introducirunaedadentre0y130aosLabel.Visible = false;                
            end            
        end

        % Value changed function: PesokgEditField
        function PesokgEditFieldValueChanged(app, event)
            if (app.PesokgEditField.Value < 0.5 || app.PesokgEditField.Value > 250)
                app.Introducirpesoentre05y250kgLabel.Visible = true;
            else
                app.eventos.PatientWeight(:) = app.PesokgEditField.Value;
                app.Introducirpesoentre05y250kgLabel.Visible = false;            
            end                        
        end

        % Value changed function: AlturacmEditField
        function AlturacmEditFieldValueChanged(app, event)
            if (app.AlturacmEditField.Value < 20 || app.AlturacmEditField.Value > 220)
                app.Introduciralturaentre20y220cmLabel.Visible = true;
            else
                app.eventos.PatientHeight(:) = app.AlturacmEditField.Value;
                app.Introduciralturaentre20y220cmLabel.Visible = false;            
            end
            % Cambio mendaje anatomia paciente
            idx = find(strcmp(app.mensaje, 'No hay información suficiente sobre la anatomía del paciente. El cálculo se realizó en un maniquí cilíndrico.'));
            app.mensaje(idx) = {'El usuario ha introducido información anatómica del paciente.'};

        end

        % Button pushed function: CalcularmapadedosisButton
        function CalcularmapadedosisButtonPushed(app, event)
            % Ejecutamos dosemapevent en el caso de DoseWise
            try
                maniqui = buscaManiqui(app.eventos(1).TargetRegion{1},app.eventos(1).PatientAge(1),...
                    app.eventos(1).PatientSex(1),app.eventos(1).PatientHeight(1),app.eventos(1).PatientWeight(1));
            catch ME
                ME2 = 'Error buscando maniquí';
                escribeLog(app.estudios,app.calclog,ME,ME2)
                uialert(app.UIFigure,'Error buscando maniquí');                
            end
            tic;
            try
                [v1, v2, v3, dosecolor, app.calclog] = SurfaceMap(app.eventos, maniqui, app.UIFigure);
            catch ME
                ME2 = 'Error calculando mapa de dosis';
                escribeLog(app.estudios,app.calclog,ME,ME2)
                uialert(app.UIFigure,'Error calculando mapa de dosis');
            end
            app.tcalc = toc;
            try
                app.mapadosis = [v1, v2, v3, dosecolor];
    %            Tras finalizar el calculo, Representamos resultados
                %clf(app.UIAxes,'reset')
                app.TabGroup.SelectedTab = app.ResultadosTab;
                vertices = [v1; v2; v3];
                caras = [(1:length(v1))' (length(v1)+1:2*length(v1))' (2*length(v1)+1:3*length(v1))'];
                c = [100, 300, 1000, 3000, 10000, 15000];
                mapa = colormap(app.UIAxes, 'jet');
                mapa(1,:) = [1 0.9784 0.7490];
                colormap(app.UIAxes, mapa); 
                p = patch(app.UIAxes,'Faces',caras,'Vertices',vertices,'FaceVertexCData', log10(dosecolor+1), 'CDataMapping','scaled','FaceColor', 'flat');
                camlight(app.UIAxes, 0, 180, 'infinite');
                material(app.UIAxes,"dull");
                caxis(app.UIAxes, [1.95, log10(15000)]);
                app.UIAxes.YDir = 'normal';
                p.EdgeColor = 'none';
                axis(app.UIAxes, 'tight');
                daspect(app.UIAxes, [1 1 1])
                titul = strcat('Mapa de dosis en la piel. PSD = ', num2str(round(max(dosecolor))),' mGy');
                app.UIAxes.Title.String = titul;
                app.UIAxes.Title.HorizontalAlignment = 'right';
                app.UIAxes.XLabel.String = '(mm)';
                app.UIAxes.YLabel.String = '(mm)';
                app.UIAxes.ZLabel.String = '(mm)';
                view(app.UIAxes, 180,-90);            
                cb = colorbar(app.UIAxes,'Ticks', log10(c), 'TickLabels', {'100','300','1000','3000','10000','15000'});
                cb.Label.String = 'Dosis absorbida (mGy)';
                cb.Location = "east";
    %            Aparece botón para crear informe
                app.CrearInformeButton.Visible = true;
            catch ME
                ME2 = 'Error dibujando mapa de dosis';
                escribeLog(app.estudios,app.calclog,ME,ME2)
                uialert(app.UIFigure,'Error dibujando mapa de dosis');
            end
        end

        % Button pushed function: CrearInformeButton
        function CrearInformeButtonPushed(app, event)
%%%%%       CREAR INFORME
        % Captura imagen gráfico
%           robot = java.awt.Robot();
%           temp = app.UIFigure.Position; % returns position as [left bottom width height]
%           pos = [temp(1) temp(2)+temp(4) temp(3) temp(4)]; % [left top width height]
%           rect = java.awt.Rectangle(pos(1),pos(2),pos(3),pos(4));
%           cap = robot.createScreenCapture(rect);
%     
%           % Convert to an RGB image
%           rgb = typecast(cap.getRGB(0,0,cap.getWidth,cap.getHeight,[],0,cap.getWidth),'uint8');
%           imgData = zeros(cap.getHeight,cap.getWidth,3,'uint8');
%           imgData(:,:,1) = reshape(rgb(3:4:end),cap.getWidth,[])';
%           imgData(:,:,2) = reshape(rgb(2:4:end),cap.getWidth,[])';
%           imgData(:,:,3) = reshape(rgb(1:4:end),cap.getWidth,[])';
%           imshow(imgData);
        
        %
        try
            %app.Informe = CrearInforme(app.estudios, app.eventos, app.mapadosis, app.mensaje, app.calclog, app.tcalc, app.UIAxes);
            app.Informe = CrearInforme(app.estudios, app.eventos, app.mapadosis, app.mensaje, app.calclog, app.tcalc, app.UIAxes);
%            uialert(app.UIFigure, app.Informe, "Depurador");
        catch ME
            ME2 = 'Error creando informe';
            escribeLog(app.estudios,app.calclog,ME,ME2)
            uialert(app.UIFigure, ME.message, ME2);
            return
        end
        % lanzar pagina web con el informe
        try
            direccion = strcat('file:///',app.Informe);
            %web(direccion, '-browser');
            [fichero, ruta, indice] = uiputfile(app.Informe);
            copyfile(app.Informe, fullfile(ruta, fichero), 'f');
        catch ME
            ME2 = 'Error publicando informe';
            escribeLog(app.estudios,app.calclog,ME,ME2)
            uialert(app.UIFigure, ME.message, ME2);            
        end
        end

        % Button pushed function: CalcularmapadedosisButton_DOLQA
        function CalcularmapadedosisButton_DOLQAPushed(app, event)
            %
            % %%%%%%%%% Ejecutamos dosemapevent en el caso de DOLQA
            %
            try
                maniqui = buscaManiqui(app.eventos.TargetRegion{1},app.eventos.PatientAge(1),...
                    app.eventos.PatientSex{1},app.eventos.PatientHeight(1),app.eventos.PatientWeight(1));
            catch ME
                ME2 = 'Error buscando maniquí';
                escribeLog(app.estudios,app.calclog,ME,ME2)
                uialert(app.UIFigure,'Error buscando maniquí');                
            end
            tic;
            try
                [v1, v2, v3, dosecolor, app.calclog] = SurfaceMap(app.eventos, maniqui, app.UIFigure);
            catch ME
                ME2 = 'Error calculando mapa de dosis';
                escribeLog(app.estudios,app.calclog,ME,ME2)
                uialert(app.UIFigure,'Error calculando mapa de dosis');
            end
            app.tcalc = toc;
            try
                app.mapadosis = [v1, v2, v3, dosecolor];
    %            Tras finalizar el calculo, Representamos resultados
                app.TabGroup.SelectedTab = app.ResultadosTab;
                vertices = [v1; v2; v3];
                caras = [(1:length(v1))' (length(v1)+1:2*length(v1))' (2*length(v1)+1:3*length(v1))'];
                c = [100, 300, 1000, 3000, 10000, 15000];
                %cla(app.UIAxes);  % borramos los ejes por si redibujamos
                cla(app.UIAxes,"reset");
                mapa = colormap(app.UIAxes, 'jet');
                mapa(1,:) = [1 0.9784 0.7490];
                colormap(app.UIAxes, mapa); 
                p = patch(app.UIAxes,'Faces',caras,'Vertices',vertices,'FaceVertexCData', log10(dosecolor+1), 'CDataMapping','scaled','FaceColor', 'flat');
                camlight(app.UIAxes, 0, 180, 'infinite');
                material(app.UIAxes,"dull");
                caxis(app.UIAxes, [1.95, log10(15000)]);
                app.UIAxes.YDir = 'normal';
                p.EdgeColor = 'none';
                axis(app.UIAxes, 'tight');
                daspect(app.UIAxes, [1 1 1])
                titul = strcat('Mapa de dosis en la piel. PSD = ', num2str(round(max(dosecolor))),' mGy');
                app.UIAxes.Title.String = titul;
                app.UIAxes.Title.HorizontalAlignment = 'right';
                app.UIAxes.XLabel.String = '(mm)';
                app.UIAxes.YLabel.String = '(mm)';
                app.UIAxes.ZLabel.String = '(mm)';
                view(app.UIAxes, 180,-90);            
                cb = colorbar(app.UIAxes,'Ticks', log10(c), 'TickLabels', {'100','300','1000','3000','10000','15000'});
                cb.Label.String = 'Dosis absorbida (mGy)';
                cb.Location = "east";
    %            Aparece botón para crear informe
                app.CrearInformeButton.Visible = true;
            catch ME
                ME2 = 'Error dibujando mapa de dosis';
                escribeLog(app.estudios,app.calclog,ME,ME2)
                uialert(app.UIFigure,'Error dibujando mapa de dosis');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InicioTab = uitab(app.TabGroup);
            app.InicioTab.Title = 'Inicio';
            app.Label = uilabel(app.InicioTab);
            app.Label.Text = {'DIDo estima la dosis en piel en pacientes que han sido sometidos a uno o más'; 'procedimientos intervencionistas.'};
            app.Image = uiimage(app.InicioTab);
            app.Image.ImageSource = 'duPont_Hybrid_Cath_Array_Architects.jpg';
            app.Label_2 = uilabel(app.InicioTab);
            app.Label_2.Text = {'Para calcular un caso sige las '; 'instrucciones a continuación.'; ''; '1. Indica qué sistema de gestión de'; 'dosis proporciona la información de '; 'los estudios:'};
            app.SistemadegestindedosisButtonGroup = uibuttongroup(app.InicioTab);
            app.SistemadegestindedosisButtonGroup.Title = 'Sistema de gestión de dosis';
            app.DOLQAButton = uiradiobutton(app.SistemadegestindedosisButtonGroup);
            app.DOLQAButton.Text = 'DOLQA';
            app.DOLQAButton.Value = true;
            app.DoseWisePortalButton = uiradiobutton(app.SistemadegestindedosisButtonGroup);
            app.DoseWisePortalButton.Text = 'DoseWisePortal';
            app.Image2 = uiimage(app.InicioTab);
            app.Image2.ImageSource = 'Dido_logo.png';
            app.DIDo2019VersinenpruebasNoaptaparausoclnicoRobertoSnchezLabel = uilabel(app.InicioTab);
            app.DIDo2019VersinenpruebasNoaptaparausoclnicoRobertoSnchezLabel.Text = 'DIDo 2019. Versión en pruebas. No apta para uso clínico. © Roberto Sánchez.';
            app.SeleccionalosarchivosLabel = uilabel(app.InicioTab);
            app.SeleccionalosarchivosLabel.Text = '2. Selecciona los archivos.';
            app.BuscararchivosButton = uibutton(app.InicioTab, 'state');
            app.BuscararchivosButton.ValueChangedFcn = createCallbackFcn(app, @BuscararchivosButtonValueChanged, true);
            app.BuscararchivosButton.Text = 'Buscar archivos';
            app.TextAreaArchivos = uitextarea(app.InicioTab);
            app.TextAreaArchivos.Editable = 'off';
            app.TextAreaArchivos.Visible = 'off';
            app.ContinuarButton = uibutton(app.InicioTab, 'state');
            app.ContinuarButton.ValueChangedFcn = createCallbackFcn(app, @ContinuarButtonValueChanged, true);
            app.ContinuarButton.Visible = 'off';
            app.ContinuarButton.Tooltip = {'Leer y verificar información del archivo.'};
            app.ContinuarButton.Text = 'Continuar';
            app.ErrorleyendoficherosVerificaelformatodelficheroLabel = uilabel(app.InicioTab);
            app.ErrorleyendoficherosVerificaelformatodelficheroLabel.Visible = 'off';
            app.ErrorleyendoficherosVerificaelformatodelficheroLabel.Text = {'Error leyendo ficheros.'; 'Verifica el formato del fichero'};
            app.DOLQATab = uitab(app.TabGroup);
            app.DOLQATab.Title = 'DOLQATab';
            app.Label_3 = uilabel(app.DOLQATab);
            app.Label_3.Text = {'Datos leídos sin errores.'; 'Puedes facilitar información adicional para mejorar la precisión del cálculo. Los campos con * son obligatorios.'};
            app.Image3 = uiimage(app.DOLQATab);
            app.Image3.ImageSource = 'Philips-Azurion-with-FlexArm-1024x577.jpg';
            app.SeleccionacentroDropDownLabel = uilabel(app.DOLQATab);
            app.SeleccionacentroDropDownLabel.Text = 'Selecciona centro *';
            app.SeleccionacentroDropDown = uidropdown(app.DOLQATab);
            app.SeleccionacentroDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccionacentroDropDownValueChanged, true);
            app.SeleccionaSalaDropDownLabel = uilabel(app.DOLQATab);
            app.SeleccionaSalaDropDownLabel.Text = 'Selecciona Sala';
            app.SeleccionaSalaDropDown = uidropdown(app.DOLQATab);
            app.SeleccionaSalaDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccionaSalaDropDownValueChanged, true);
            app.SinoseleccionassalaserealizarlaestimacinconunasalaestndarLabel = uilabel(app.DOLQATab);
            app.SinoseleccionassalaserealizarlaestimacinconunasalaestndarLabel.Text = 'Si no seleccionas sala, se realizará la estimación de dosis con una sala estándar';
            app.PesokgLabel = uilabel(app.DOLQATab);
            app.PesokgLabel.Text = 'Peso (kg) ';
            app.PesokgEditField = uieditfield(app.DOLQATab, 'numeric');
            app.PesokgEditField.ValueChangedFcn = createCallbackFcn(app, @PesokgEditFieldValueChanged, true);
            app.DatosdelpacienteLabel = uilabel(app.DOLQATab);
            app.DatosdelpacienteLabel.Text = 'Datos del paciente:';
            app.EdadaosEditFieldLabel = uilabel(app.DOLQATab);
            app.EdadaosEditFieldLabel.Text = 'Edad (años)';
            app.EdadaosEditField = uieditfield(app.DOLQATab, 'numeric');
            app.EdadaosEditField.ValueChangedFcn = createCallbackFcn(app, @EdadaosEditFieldValueChanged, true);
            app.AlturacmEditFieldLabel = uilabel(app.DOLQATab);
            app.AlturacmEditFieldLabel.Text = 'Altura (cm)';
            app.AlturacmEditField = uieditfield(app.DOLQATab, 'numeric');
            app.AlturacmEditField.ValueChangedFcn = createCallbackFcn(app, @AlturacmEditFieldValueChanged, true);
            app.InformaDatosPaciente = uilabel(app.DOLQATab);
            app.InformaDatosPaciente.Text = 'Si se dejan en blanco, el cálculo de dosis en piel se realizará en un maniquí elíptico.';
            app.SexoLabel = uilabel(app.DOLQATab);
            app.SexoLabel.Text = 'Sexo';
            app.SeleccionarSexo = uidropdown(app.DOLQATab);
            app.SeleccionarSexo.Items = {'Desconocido', 'Male', 'Female'};
            app.SeleccionarSexo.ValueChangedFcn = createCallbackFcn(app, @SeleccionarSexoValueChanged, true);
            app.SeleccionarSexo.Value = 'Male';
            app.Introducirunaedadentre0y130aosLabel = uilabel(app.DOLQATab);
            app.Introducirunaedadentre0y130aosLabel.Visible = 'off';
            app.Introducirunaedadentre0y130aosLabel.Text = 'Introducir una edad entre 0 y 130 años';
            app.Introducirpesoentre05y250kgLabel = uilabel(app.DOLQATab);
            app.Introducirpesoentre05y250kgLabel.Visible = 'off';
            app.Introducirpesoentre05y250kgLabel.Text = 'Introducir peso entre 0.5 y 250 kg';
            app.Introduciralturaentre20y220cmLabel = uilabel(app.DOLQATab);
            app.Introduciralturaentre20y220cmLabel.Visible = 'off';
            app.Introduciralturaentre20y220cmLabel.Text = 'Introducir altura entre 20 y 220 cm';
            app.CalcularmapadedosisButton_DOLQA = uibutton(app.DOLQATab, 'push');
            app.CalcularmapadedosisButton_DOLQA.ButtonPushedFcn = createCallbackFcn(app, @CalcularmapadedosisButton_DOLQAPushed, true);
            app.CalcularmapadedosisButton_DOLQA.Visible = 'off';
            app.CalcularmapadedosisButton_DOLQA.Text = 'Calcular mapa de dosis';
            app.DoseWiseTab = uitab(app.TabGroup);
            app.DoseWiseTab.Title = 'DoseWiseTab';
            app.InformacionerroresLabel = uilabel(app.DoseWiseTab);
            app.InformacionerroresLabel.Text = {'Informacion:'; 'errores'; ''};
            app.Image4 = uiimage(app.DoseWiseTab);
            app.Image4.ImageSource = '436555ree.jpg';
            app.CalcularmapadedosisButton = uibutton(app.DoseWiseTab, 'push');
            app.CalcularmapadedosisButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularmapadedosisButtonPushed, true);
            app.CalcularmapadedosisButton.Visible = 'off';
            app.CalcularmapadedosisButton.Text = 'Calcular mapa de dosis';
            app.ResultadosTab = uitab(app.TabGroup);
            app.ResultadosTab.Title = 'Resultados';
            app.UIAxes = uiaxes(app.ResultadosTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CrearInformeButton = uibutton(app.ResultadosTab, 'push');
            app.CrearInformeButton.ButtonPushedFcn = createCallbackFcn(app, @CrearInformeButtonPushed, true);
            app.CrearInformeButton.Visible = 'off';
            app.CrearInformeButton.Text = 'Crear Informe';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dido02

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end